classdef EE305GroupProject4MatlabCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EE305GroupProject4            matlab.ui.Figure
        MagneticFieldoscillatinginydirectionPanel  matlab.ui.container.Panel
        UIAxes3                       matlab.ui.control.UIAxes
        ElectricFieldPanel            matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        ParametersPanel               matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        radLabel                      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        mLabel_2                      matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        radsLabel                     matlab.ui.control.Label
        VmLabel                       matlab.ui.control.Label
        jEditField                    matlab.ui.control.NumericEditField
        jEditFieldLabel               matlab.ui.control.Label
        AttenuationConstantEditField  matlab.ui.control.NumericEditField
        AttenuationConstantLabel      matlab.ui.control.Label
        InstrinsicimpedenceEditField  matlab.ui.control.NumericEditField
        InstrinsicimpedenceLabel      matlab.ui.control.Label
        PlotEMwaveButton              matlab.ui.control.Button
        PhasedelayEditField           matlab.ui.control.NumericEditField
        PhasedelayLabel               matlab.ui.control.Label
        PhaseconstantEditField        matlab.ui.control.NumericEditField
        PhaseconstantLabel            matlab.ui.control.Label
        AngularFrequencyEditField     matlab.ui.control.NumericEditField
        AngularFrequencyLabel         matlab.ui.control.Label
        ElectricfieldamplitudeEEditField  matlab.ui.control.NumericEditField
        ElectricfieldamplitudeELabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        MagneticFieldRedLabel         matlab.ui.control.Label
        ZaxislimitmSlider             matlab.ui.control.Slider
        ZaxislimitmSliderLabel        matlab.ui.control.Label
        ElectricFieldBlueLabel        matlab.ui.control.Label
        TimesSlider                   matlab.ui.control.Slider
        TimesSliderLabel              matlab.ui.control.Label
        ElectromagneticWavePropogatinginZdirectionLabel  matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PlotEMwaveButton, ZaxislimitmSlider
        function LoadButtonPushed(app, event)
            t = app.TimesSlider.Value;
            E0 = app.ElectricfieldamplitudeEEditField.Value;
            beta_value = app.PhaseconstantEditField.Value;
            ang_freq = app.AngularFrequencyEditField.Value;
            phi = app.PhasedelayEditField.Value;
            etareal = app.InstrinsicimpedenceEditField.Value;
            etaimag = app.jEditField.Value;
            alpha = app.AttenuationConstantEditField.Value;
            eta=complex(etareal,etaimag);
            theta = angle(eta);
            etamod= sqrt(etareal*etareal + etaimag*etaimag);
            % changing the z axes
            distance = floor(app.ZaxislimitmSlider.Value);
            no_of_points = 300;
            z = 1:no_of_points;
            % 'no_of_points' points in range of 1 to 10, here, 300
            x_limit = distance;
            z_axis = 1+(x_limit-1)/no_of_points:(x_limit-1)/no_of_points:x_limit;
            x = zeros(1, no_of_points);
            for z_indv = z
                x(1, z_indv) = E0*exp(-alpha*z_axis(z_indv))*cos(ang_freq*t-beta_value*z_axis(z_indv)+phi);
            end
             y = zeros(1, no_of_points);
            for z_indv = z
                y(1, z_indv) = E0*exp(-alpha*z_axis(z_indv))*cos(ang_freq*t+phi-theta-beta_value*z_axis(z_indv));
            end
            % Plotting electric field
            plot(app.UIAxes2, z_axis, x);
            xlim(app.UIAxes2,[z_axis(1) z_axis(no_of_points)]);
            % Plotting magnetic field
            plot(app.UIAxes3, z_axis, y/etamod, 'red');
            % Plotting in 3D
            plot3(app.UIAxes4, x, z_axis, zeros(no_of_points), 'blue', zeros(no_of_points), z_axis, y/etamod, 'red', zeros(no_of_points), z_axis, zeros(no_of_points),  'black');
        end

        % Value changing function: TimesSlider
        function TimesSliderValueChanging(app, event)
            changingValue = event.Value;
            t = changingValue;
            E0 = app.ElectricfieldamplitudeEEditField.Value;
            beta_value = app.PhaseconstantEditField.Value;
            ang_freq = app.AngularFrequencyEditField.Value;
            phi = app.PhasedelayEditField.Value;
            etareal = app.InstrinsicimpedenceEditField.Value;
            etaimag = app.jEditField.Value;
            alpha = app.AttenuationConstantEditField.Value;
            eta=complex(etareal,etaimag);
            theta = angle(eta);
            etamod= sqrt(etareal*etareal + etaimag*etaimag);
            % changing the z axes
            distance = floor(app.ZaxislimitmSlider.Value);
            no_of_points = 300;
            z = 1:no_of_points;
            % 'no_of_points' points in range of 1 to 10, here, 300
            x_limit = distance;
            z_axis = 1+(x_limit-1)/no_of_points:(x_limit-1)/no_of_points:x_limit;
            x = zeros(1, no_of_points);
            for z_indv = z
                x(1, z_indv) = E0*exp(-alpha*z_axis(z_indv))*cos(ang_freq*t-beta_value*z_axis(z_indv)+phi);
            end
             y = zeros(1, no_of_points);
            for z_indv = z
                y(1, z_indv) = E0*exp(-alpha*z_axis(z_indv))*cos(ang_freq*t+phi-theta-beta_value*z_axis(z_indv));
            end
            % Plotting electric field
            plot(app.UIAxes2, z_axis, x);
            xlim(app.UIAxes2,[z_axis(1) z_axis(no_of_points)]);
            % Plotting magnetic field
            plot(app.UIAxes3, z_axis, y/etamod, 'red');
            % Plotting in 3D
            plot3(app.UIAxes4, x, z_axis, zeros(no_of_points), 'blue', zeros(no_of_points), z_axis, y/etamod, 'red', zeros(no_of_points), z_axis, zeros(no_of_points),  'black');
        end

        % Callback function
        function DistanceSliderValueChanged(app, event)
            
        end

        % Button down function: UIAxes4
        function DistanceSliderValueChanged2(app, event)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TimesSlider.Value=0;
            app.ElectricfieldamplitudeEEditField.Value=0;
            app.PhaseconstantEditField.Value=0;
            app.AngularFrequencyEditField.Value=0;
            app.PhasedelayEditField.Value=0;
            app.InstrinsicimpedenceEditField.Value=0;
            app.jEditField.Value=0;
            app.AttenuationConstantEditField.Value=0;
            LoadButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EE305GroupProject4 = uifigure('Visible', 'off');
            app.EE305GroupProject4.Name = 'EE 305 Group Project 4';
            app.Panel = uipanel(app.EE305GroupProject4);
            app.UIAxes4 = uiaxes(app.Panel);
            xlabel(app.UIAxes4, 'Electric field (V/m)')
            ylabel(app.UIAxes4, 'Z (distance in m)')
            zlabel(app.UIAxes4, 'Magnetic field intensity (A/m)')
            app.UIAxes4.YDir = 'reverse';
            app.UIAxes4.XAxisLocation = 'top';
            app.UIAxes4.YAxisLocation = 'right';
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @DistanceSliderValueChanged2, true);
            app.ElectromagneticWavePropogatinginZdirectionLabel = uilabel(app.Panel);
            app.ElectromagneticWavePropogatinginZdirectionLabel.Text = 'Electromagnetic Wave Propogating in Z direction';
            app.TimesSliderLabel = uilabel(app.Panel);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.TimesSlider = uislider(app.Panel);
            app.TimesSlider.ValueChangingFcn = createCallbackFcn(app, @TimesSliderValueChanging, true);
            app.TimesSlider.Value = 20;
            app.ElectricFieldBlueLabel = uilabel(app.Panel);
            app.ElectricFieldBlueLabel.Text = 'Electric Field - Blue';
            app.ZaxislimitmSliderLabel = uilabel(app.Panel);
            app.ZaxislimitmSliderLabel.Text = 'Z-axis limit (m)';
            app.ZaxislimitmSlider = uislider(app.Panel);
            app.ZaxislimitmSlider.Limits = [4 100];
            app.ZaxislimitmSlider.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.ZaxislimitmSlider.Value = 10;
            app.MagneticFieldRedLabel = uilabel(app.Panel);
            app.MagneticFieldRedLabel.Text = 'Magnetic Field - Red';
            app.ParametersPanel = uipanel(app.EE305GroupProject4);
            app.ParametersPanel.Title = 'Parameters';
            app.ElectricfieldamplitudeELabel = uilabel(app.ParametersPanel);
            app.ElectricfieldamplitudeELabel.Text = {'Electric field'; ' amplitude (E₀)'};
            app.ElectricfieldamplitudeEEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ElectricfieldamplitudeEEditField.Value = 10;
            app.AngularFrequencyLabel = uilabel(app.ParametersPanel);
            app.AngularFrequencyLabel.Text = {'Angular'; ' Frequency (ω)'};
            app.AngularFrequencyEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.AngularFrequencyEditField.Value = 100;
            app.PhaseconstantLabel = uilabel(app.ParametersPanel);
            app.PhaseconstantLabel.Text = 'Phase constant (β)';
            app.PhaseconstantEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PhaseconstantEditField.Value = 10;
            app.PhasedelayLabel = uilabel(app.ParametersPanel);
            app.PhasedelayLabel.Text = 'Phase delay (Φ)';
            app.PhasedelayEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PhasedelayEditField.Value = 5;
            app.PlotEMwaveButton = uibutton(app.ParametersPanel, 'push');
            app.PlotEMwaveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.PlotEMwaveButton.Text = 'Plot EM wave';
            app.InstrinsicimpedenceLabel = uilabel(app.ParametersPanel);
            app.InstrinsicimpedenceLabel.Text = {'Instrinsic'; ' impedence (η)'};
            app.InstrinsicimpedenceEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.InstrinsicimpedenceEditField.Value = 2;
            app.AttenuationConstantLabel = uilabel(app.ParametersPanel);
            app.AttenuationConstantLabel.Text = {'Attenuation'; ' Constant (α)'};
            app.AttenuationConstantEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.AttenuationConstantEditField.Value = 0.1;
            app.jEditFieldLabel = uilabel(app.ParametersPanel);
            app.jEditFieldLabel.Text = '+j';
            app.jEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.jEditField.Value = 3;
            app.VmLabel = uilabel(app.ParametersPanel);
            app.VmLabel.Text = 'V/m';
            app.radsLabel = uilabel(app.ParametersPanel);
            app.radsLabel.Text = 'rad/s';
            app.mLabel = uilabel(app.ParametersPanel);
            app.mLabel.Text = '/m';
            app.mLabel_2 = uilabel(app.ParametersPanel);
            app.mLabel_2.Text = '/m';
            app.Label = uilabel(app.ParametersPanel);
            app.Label.Text = 'Ω';
            app.radLabel = uilabel(app.ParametersPanel);
            app.radLabel.Text = 'rad';
            app.ResetButton = uibutton(app.ParametersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ElectricFieldPanel = uipanel(app.EE305GroupProject4);
            app.ElectricFieldPanel.Title = 'Electric Field oscillating in x direction';
            app.UIAxes2 = uiaxes(app.ElectricFieldPanel);
            xlabel(app.UIAxes2, 'Z (distance in m)')
            ylabel(app.UIAxes2, 'X (V/m)')
            zlabel(app.UIAxes2, 'Y')
            app.MagneticFieldoscillatinginydirectionPanel = uipanel(app.EE305GroupProject4);
            app.MagneticFieldoscillatinginydirectionPanel.Title = 'Magnetic Field oscillating in y direction';
            app.UIAxes3 = uiaxes(app.MagneticFieldoscillatinginydirectionPanel);
            xlabel(app.UIAxes3, 'Z (distance in m)')
            ylabel(app.UIAxes3, 'Y (A/m)')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.EE305GroupProject4.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE305GroupProject4MatlabCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EE305GroupProject4)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EE305GroupProject4)
        end
    end
end